classdef beamforming < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        focusontargetButton         matlab.ui.control.Button
        targetxcoordinateLabel      matlab.ui.control.Label
        s_targetx                   matlab.ui.control.Slider
        targetycoordinateLabel      matlab.ui.control.Label
        s_targety                   matlab.ui.control.Slider
        BeamformingSimulationLabel  matlab.ui.control.Label
        Gauge                       matlab.ui.control.Gauge
        AmplitudeattargetLabel      matlab.ui.control.Label
        Slider                      matlab.ui.control.Slider
        numberofsourcesLabel        matlab.ui.control.Label
        recalculateButton           matlab.ui.control.Button
    end


    properties (Access = private)
        x_target = 25; % target x coordinate
        y_target = 25; % target y coordinate
        x_sources = [10 40 10 40 25 25 10 40]; % sources x coordinate
        y_sources = [10 40 40 10 10 40 25 25]; % sources y coordinate
        nr_sources = 8;
        % space and time parameters
        dx = 0.2
        x_min = 0;
        x_max = 50;
        dy = 0.2;
        y_min = 0;
        y_max = 50;
        dt = 0.05;
        t_min = 0;
        t_max = 2*pi;
        lambda = 2*pi;
        x;
        y;
    end

    methods (Access = private)
    
        function results = main(app)
            [app.x, app.y] = meshgrid(app.x_min:app.dx:app.x_max,app.y_min:app.dy:app.y_max);
            distance = sqrt((app.x_target-app.x_sources).^2+(app.y_target-app.y_sources).^2);
            phaseshift = distance-floor(distance/app.lambda)*app.lambda;
            
            phi = zeros(app.nr_sources, 1);
            for i = 1:app.nr_sources
                phi(i) = app.computeAngle(app.x_sources(i), app.y_sources(i), app.x_target, app.y_target);
            end
            
            minimum = zeros(size(app.x, 1), size(app.y, 1));
            maximum = zeros(size(app.x, 1), size(app.y, 1));
            for t = app.t_min:app.dt:app.t_max+app.dt
                all = 0;
                % compute wave for each source and overlay
                for i = 1:app.nr_sources
                    % compute distance relative to wave source
                    positions = sqrt((app.x-app.x_sources(i)).^2+(app.y-app.y_sources(i)).^2);
                    % compute angle relativ to wave source
                    psi = app.computeAngle(app.x_sources(i), app.y_sources(i), app.x, app.y);
                    % compute angle gain
                    gain = app.angleDamping(psi, phi(i));
        
                    % compute actual wave
                    part = sin(positions-t-phaseshift(i)).*gain;
                    all = all+part;
                end
    
                % check for each point, whether the overlay reaches a new min/max
                if t == app.t_min
                    minimum = all;
                    maximum = all;
                else
                    minimum = app.getMinMatrix(minimum, all);
                    maximum = app.getMaxMatrix(maximum, all);
                end
            end
            amp = maximum-minimum;
            
            surf(app.UIAxes,app.x, app.y, amp);
            rotate3d(app.UIAxes,'on');
            hold(app.UIAxes,'on');
            plot3(app.UIAxes,[app.x_target app.x_target], [app.y_target app.y_target], [0 2*app.nr_sources+1], 'r');
            for i = 1:app.nr_sources
                plot3(app.UIAxes,[app.x_sources(i) app.x_sources(i)], [app.y_sources(i) app.y_sources(i)], [0 2*app.nr_sources+1], 'g');
            end
            hold(app.UIAxes,'off');
            
            % find amplitude at target point
            k_x = (app.x_target-app.x_min)/app.dx+1;
            k_y = (app.y_target-app.y_min)/app.dy+1;           
            app.Gauge.Value = amp(k_x,k_y);
        end
        
        function phi = computeAngle(app,x_s, y_s, x_t, y_t)
             s = size(x_t);
             phi = zeros(s);
             for i = 1:s(1)
                for j = 1:s(2)
                    delta_x = x_t(i, j)-x_s;
                    delta_y = y_t(i, j)-y_s;

                    phi(i, j) = atan(delta_y/delta_x);

                    if delta_x <0
                        if delta_y < 0
                            phi(i, j) = phi(i, j)-pi;
                        else
                            phi(i, j) = pi+phi(i, j);
                        end
                    end
                end
             end
        end
        
        function results = getMinMatrix(app,x1, x2)
            results = zeros(size(x1));    
            for i = 1:size(x1, 1)
                for j = 1:size(x1, 2)
                    results(i, j) = min(x1(i, j), x2(i, j)); 
                end
            end
        end
        
        function results = getMaxMatrix(app,x1, x2)
             results = zeros(size(x1));
    
             for i = 1:size(x1, 1)
                for j = 1:size(x1, 2)
                    results(i, j) = max(x1(i, j), x2(i, j)); 
                end
             end            
        end
        
        function gain = angleDamping(app,psi, phi)
            piH = pi/2;
            s = size(psi);
    
            gain = zeros(s);
    
            for i = 1:s(1)
                for j = 1:s(2)
                    if (phi <= piH) && (phi >= -piH)
                        if (psi(i, j) <= phi+piH) && (psi(i, j) >= phi-piH)
                            gain(i, j) = (1+cos(2*(psi(i, j)-phi)))/2;
                        end
                    elseif phi > piH
                        if (psi(i, j) >= phi-piH) || (psi(i, j) <= -(2*pi-piH-phi))
                            gain(i, j) = (1+cos(2*(psi(i, j)-phi)))/2;
                        end
                     else
                        if (psi(i, j) <= phi+piH) || (psi(i, j) >= (2*pi-pi/2-phi))
                            gain(i, j) = (1+cos(2*(psi(i, j)-phi)))/2;
                        end
                    end
                end
            end
        end
        
    end


    methods (Access = private)

        % Button pushed function: focusontargetButton
        function focusontargetButtonPushed(app, event)
            app.main()
        end

        % Value changed function: s_targetx
        function s_targetxValueChanged(app, event)
            app.x_target = app.s_targetx.Value;            
        end

        % Value changed function: s_targety
        function s_targetyValueChanged(app, event)
            app.y_target = app.s_targety.Value;            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.nr_sources = floor(app.Slider.Value);
            
        end

        % Button pushed function: recalculateButton
        function recalculateButtonPushed(app, event)
            app.main()
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.focusontargetButton = uibutton(app.UIFigure, 'push');
            app.focusontargetButton.ButtonPushedFcn = createCallbackFcn(app, @focusontargetButtonPushed, true);
            app.focusontargetButton.Text = 'focus on target';
            app.targetxcoordinateLabel = uilabel(app.UIFigure);
            app.targetxcoordinateLabel.Text = 'target x coordinate';
            app.s_targetx = uislider(app.UIFigure);
            app.s_targetx.Limits = [0 50];
            app.s_targetx.ValueChangedFcn = createCallbackFcn(app, @s_targetxValueChanged, true);
            app.s_targetx.Value = 25;
            app.targetycoordinateLabel = uilabel(app.UIFigure);
            app.targetycoordinateLabel.Text = 'target y coordinate';
            app.s_targety = uislider(app.UIFigure);
            app.s_targety.Limits = [0 50];
            app.s_targety.ValueChangedFcn = createCallbackFcn(app, @s_targetyValueChanged, true);
            app.s_targety.Value = 25;
            app.BeamformingSimulationLabel = uilabel(app.UIFigure);
            app.BeamformingSimulationLabel.Text = 'Beamforming Simulation';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge.Limits = [0 18];
            app.AmplitudeattargetLabel = uilabel(app.UIFigure);
            app.AmplitudeattargetLabel.Text = 'Amplitude at target';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 8];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 8;
            app.numberofsourcesLabel = uilabel(app.UIFigure);
            app.numberofsourcesLabel.Text = 'number of sources';
            app.recalculateButton = uibutton(app.UIFigure, 'push');
            app.recalculateButton.ButtonPushedFcn = createCallbackFcn(app, @recalculateButtonPushed, true);
            app.recalculateButton.Text = 'recalculate';
        end
    end

    methods (Access = public)

        % Construct app
        function app = beamforming

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end